classdef Resultados_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnguloEditField                 matlab.ui.control.NumericEditField
        AnguloEditFieldLabel            matlab.ui.control.Label
        SigmaXYEditField                matlab.ui.control.NumericEditField
        SigmaXYEditFieldLabel           matlab.ui.control.Label
        ValoresdeentradaLabel           matlab.ui.control.Label
        SigmaYEditField                 matlab.ui.control.NumericEditField
        SigmaYEditFieldLabel            matlab.ui.control.Label
        SigmaXEditField                 matlab.ui.control.NumericEditField
        SigmaXEditFieldLabel            matlab.ui.control.Label
        EsfuerzodefluenciaEditField     matlab.ui.control.NumericEditField
        EsfuerzodefluenciaEditFieldLabel  matlab.ui.control.Label
        SigmaPromedioEditField          matlab.ui.control.NumericEditField
        SigmaPromedioEditFieldLabel     matlab.ui.control.Label
        VERGRAFICASButton               matlab.ui.control.Button
        FSTrescaEditField               matlab.ui.control.NumericEditField
        FSTrescaEditFieldLabel          matlab.ui.control.Label
        FSVonMisesEditField             matlab.ui.control.NumericEditField
        FSVonMisesEditFieldLabel        matlab.ui.control.Label
        CriterioTrescaLabel             matlab.ui.control.Label
        CriterioVonMisesLabel           matlab.ui.control.Label
        CriteriosdefallaLabel           matlab.ui.control.Label
        AlphaMaxSEditField              matlab.ui.control.NumericEditField
        AlphaMaxSEditFieldLabel         matlab.ui.control.Label
        ngulodelesfuerzomximocortanteLabel  matlab.ui.control.Label
        AlphaMaxPEditField              matlab.ui.control.NumericEditField
        AlphaMaxPEditFieldLabel         matlab.ui.control.Label
        ngulodelosesfuerzosmximosLabel  matlab.ui.control.Label
        SigmaXYMaxEditField             matlab.ui.control.NumericEditField
        SigmaXYMaxEditFieldLabel        matlab.ui.control.Label
        EsfuerzocortantemximoLabel      matlab.ui.control.Label
        SigmaMinEditField               matlab.ui.control.NumericEditField
        SigmaMinEditFieldLabel          matlab.ui.control.Label
        SigmaMaxEditField               matlab.ui.control.NumericEditField
        SigmaMaxEditFieldLabel          matlab.ui.control.Label
        EsfuerzosprincipalesLabel       matlab.ui.control.Label
        EsfuerzossegnngulodadoLabel     matlab.ui.control.Label
        SigmaXYPrimaEditField           matlab.ui.control.NumericEditField
        SigmaXYPrimaEditFieldLabel      matlab.ui.control.Label
        SigmaYPrimaEditField            matlab.ui.control.NumericEditField
        SigmaYPrimaEditFieldLabel       matlab.ui.control.Label
        SigmaXPrimaEditField            matlab.ui.control.NumericEditField
        SigmaXPrimaEditFieldLabel       matlab.ui.control.Label
        RESULTADOSLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, alpha, eFluenciaU, sigmaPromedio, sigmaXPrima, sigmaYPrima, sigmaXYPrima, sigmaXMax, sigmaXMin, sigmaXYMax, alphaMaxP, alphaMaxS, fsTresca, fsVonMises, SigmaXu, SigmaYu, SigmaXYu)
                  
            app.SigmaXPrimaEditField.Value= sigmaXPrima;
            app.SigmaYPrimaEditField.Value= sigmaYPrima;
            app.SigmaXYPrimaEditField.Value= sigmaXYPrima;
            app.SigmaMaxEditField.Value= sigmaXMax;
            app.SigmaMinEditField.Value= sigmaXMin;
            app.SigmaXYMaxEditField.Value= sigmaXYMax;
            app.AlphaMaxPEditField.Value= alphaMaxP;
            app.AlphaMaxSEditField.Value= alphaMaxS;
            app.FSTrescaEditField.Value= fsTresca;
            app.FSVonMisesEditField.Value= fsVonMises;
            app.SigmaPromedioEditField.Value= sigmaPromedio;
            app.EsfuerzodefluenciaEditField.Value= eFluenciaU
            app.SigmaXEditField.Value= SigmaXu
            app.SigmaYEditField.Value= SigmaYu
            app.SigmaXYEditField.Value= SigmaXYu
            app.AnguloEditField.Value= alpha

        end

        % Button pushed function: VERGRAFICASButton
        function VERGRAFICASButtonPushed(app, event)
      %%Calcular circulo de Mohr
    figure;
     global Sigmaprom;
     global x
     global y
     global prom
     global Taomax
     global r
     
     Taomax=app.SigmaXYMaxEditField.Value;
     Sigmaprom=app.SigmaPromedioEditField.Value;
    
     r=Taomax;
     prom=Sigmaprom;
    global anguloa
    anguloa=linspace(0,2*pi);
    x=(r*cos(anguloa))+prom;
    y=(r*sin(anguloa));
    plot(x,y); %%PASAR A GRAFICAS
    %graficar puntos circulo de mohr completo
    global Sigmax
    global Sigmay
    global Sigmaxy
    global Sigmaxyy
    global Sigmaxyx
    global angulo
    global Sigmaxp
    global Sigmayp
    global Sigmaxyp
    global Sigmamax
    global Sigmamin
    global alphas
    global alphap
    hold on;
    Sigmax=app.SigmaXEditField.Value;
     Sigmay=app.SigmaYEditField.Value;
    Sigmaxy=app.SigmaXYEditField.Value;
    Sigmaxyy=-Sigmaxy;
    Sigmaxyx=Sigmaxy;
    angulo=app.AnguloEditField.Value;
    Sigmaxp=app.SigmaXPrimaEditField.Value;
    Sigmayp=app.SigmaYPrimaEditField.Value;
    Sigmaxyp=app.SigmaXYMaxEditField.Value
    Sigmamax=app.SigmaMaxEditField.Value;
    Sigmamin=app.SigmaMinEditField.Value;
    alphas=app.AlphaMaxSEditField.Value;
    alphap=app.AlphaMaxPEditField.Value;
    scatter(Sigmax,Sigmaxyx,'filled','MarkerFaceColor','r');
    text(Sigmax+0.2,Sigmaxyx+0.2,'Sigmaxx'); 
    hold on;
    scatter(Sigmay,Sigmaxyy,'filled','MarkerFaceColor','r');
    text(Sigmay+0.2,Sigmaxyy+0.2,'Sigmayy');
    hold on;
    plot([Sigmax Sigmay],[Sigmaxyx Sigmaxyy ]);
    hold on;
    scatter(Sigmamax,0,'filled','MarkerFaceColor','r');
    text(Sigmamax+0.2,0.2,'Sigmax');
    hold on;
     scatter(Sigmamin,0,'filled','MarkerFaceColor','r');
    text(Sigmamin+0.2,0.2,'Sigmamin');
    hold on;
     plot([Sigmamax Sigmamin],[0 0]);
    hold on;    
    scatter(Sigmaxp,Sigmaxyp,'filled','MarkerFaceColor','r');
    text(Sigmaxp+0.2,Sigmaxyp+0.2,"Sigmax'x'");
    hold on;
    scatter(Sigmayp,-Sigmaxyp,'filled','MarkerFaceColor','r');
    text(Sigmayp+0.2,-Sigmaxyp+0.2,"Sigmay'y'");
    hold on;
    plot([Sigmaxp Sigmayp],[Sigmaxyp -Sigmaxyp]);
    hold on;
  
    % Agregar títulos a los ejes y a la gráfica
    xlabel('Sigma');
    ylabel('Tau');
    title('Circulo de Mohr con Transformación de esfuerzos planos ');
   
%%Esfuerzo en funcion del angulo
   global AlphamaxP
   global AlphamaxS
   global Sigmaxus
   global Sigmayus
   global Sigmaxyus;
   Sigmaxus=app.SigmaXEditField.Value;
   Sigmayus=app.SigmaYEditField.Value;
   Sigmaxyus=app.SigmaXYEditField.Value;
   AlphamaxS=app.AlphaMaxSEditField.Value*(pi/180);
   AlphamaxP=app.AlphaMaxPEditField.Value*(pi/180);
   angulo=app.AnguloEditField.Value*(pi/180);
    anguloa=linspace(0,2*pi);
    global Sigmaxpf
    global Sigmaxypf
    Sigmaxpf= (((Sigmaxus+Sigmayus)/2))+(((Sigmaxus-Sigmayus)/2)*cos(2*anguloa))+(Sigmaxyus*sin(2*anguloa));
    Sigmaxypf=-(((Sigmaxus-Sigmayus)/2))*sin(2*anguloa)+(Sigmaxyus*cos(2*anguloa));
    figure(2);
    plot(anguloa,Sigmaxpf);
    hold on;
     scatter(AlphamaxP+(pi/2),Sigmamax,'filled','MarkerFaceColor','r');
        hold on;
     scatter(angulo,Sigmaxp,'filled','MarkerFaceColor','b');
     xlabel('Angulo');
    ylabel('Esfuerzo');
    title('Esfuerzo Vs Angulo para Esfuerzos normales');

     figure(3);
    plot(anguloa,Sigmaxypf);
    hold on;
    scatter(AlphamaxP+(3*pi/4),-r,'filled','MarkerFaceColor','r');
    text(Sigmamin+0.2,0.2,'Sigmamin');
    hold on;
    scatter(angulo,Sigmaxyp,'filled','MarkerFaceColor','b');
    text(Sigmamin+0.2,0.2,'Sigmamin');
    % Agregar títulos a los ejes y a la gráfica
    xlabel('Angulo');
    ylabel('Esfuerzo');
    title('Esfuerzo Vs Angulo para Esfuerzos cortantes');
       %%Von mises y tresca
       %TRESCA
       figure(4);
       global fluencia
fluencia = app.EsfuerzodefluenciaEditField.Value;
       %unir puntos de fluencia

  plot([0 fluencia],[fluencia fluencia],'r');
  hold on;
  plot([fluencia fluencia],[fluencia 0],'r');
  hold on;
  plot([fluencia 0],[0 -fluencia],'r');
  hold on;
  plot([0 -fluencia],[-fluencia -fluencia],'r');
  hold on;
  plot([-fluencia -fluencia],[-fluencia 0],'r');
  hold on;
  plot([-fluencia 0],[0 fluencia],'r');
  % Ampliar los ejes
        xMin = -1.3 * fluencia; % Factor de ampliación
        xMax = 1.3 * fluencia;
        yMin = -1.3 * fluencia;
        yMax = 1.3* fluencia;
        xlim([xMin, xMax]);
        ylim([yMin, yMax]);
        hold on;
            % VON MISES
        %Definir la función simbólica
        % Definir la función simbólica
        global fluencia;
        fluencia = app.EsfuerzodefluenciaEditField.Value;
        syms a b;
        eqn = (a^2) - (a*b) + (b^2) - fluencia^2;
        
        % Graficar la función simbólica
        ezplot(eqn, [-fluencia-0.5*fluencia, fluencia+0.5*fluencia]);
        hold on;
        scatter(Sigmamax,Sigmamin,'filled','MarkerFaceColor','r');
         text(Sigmamax+0.2,Sigmamin+0.2,'Sigmamax,Sigmamin');
        xlabel('sigma1');
        ylabel('sigma2');
        title('Criterio de Falla Tresca (rojo) Vs Von Misses(azul)');
        
         close(app.UIFigure)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RESULTADOSLabel = uilabel(app.UIFigure);
            app.RESULTADOSLabel.Text = 'RESULTADOS';
            app.SigmaXPrimaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXPrimaEditFieldLabel.Text = 'Sigma X Prima';
            app.SigmaXPrimaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaYPrimaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaYPrimaEditFieldLabel.Text = 'Sigma Y Prima';
            app.SigmaYPrimaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaXYPrimaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXYPrimaEditFieldLabel.Text = 'Sigma XY Prima';
            app.SigmaXYPrimaEditField = uieditfield(app.UIFigure, 'numeric');
            app.EsfuerzossegnngulodadoLabel = uilabel(app.UIFigure);
            app.EsfuerzossegnngulodadoLabel.Text = 'Esfuerzos según ángulo dado';
            app.EsfuerzosprincipalesLabel = uilabel(app.UIFigure);
            app.EsfuerzosprincipalesLabel.Text = 'Esfuerzos principales';
            app.SigmaMaxEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaMaxEditFieldLabel.Text = 'Sigma Max';
            app.SigmaMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaMinEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaMinEditFieldLabel.Text = 'Sigma Min ';
            app.SigmaMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.EsfuerzocortantemximoLabel = uilabel(app.UIFigure);
            app.EsfuerzocortantemximoLabel.Text = 'Esfuerzo cortante máximo';
            app.SigmaXYMaxEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXYMaxEditFieldLabel.Text = 'Sigma XY Max';
            app.SigmaXYMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ngulodelosesfuerzosmximosLabel = uilabel(app.UIFigure);
            app.ngulodelosesfuerzosmximosLabel.Text = 'Ángulo de los esfuerzos máximos';
            app.AlphaMaxPEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaMaxPEditFieldLabel.Text = 'Alpha Max P';
            app.AlphaMaxPEditField = uieditfield(app.UIFigure, 'numeric');
            app.ngulodelesfuerzomximocortanteLabel = uilabel(app.UIFigure);
            app.ngulodelesfuerzomximocortanteLabel.Text = 'Ángulo del esfuerzo máximo cortante';
            app.AlphaMaxSEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaMaxSEditFieldLabel.Text = 'Alpha Max S';
            app.AlphaMaxSEditField = uieditfield(app.UIFigure, 'numeric');
            app.CriteriosdefallaLabel = uilabel(app.UIFigure);
            app.CriteriosdefallaLabel.Text = 'Criterios de falla';
            app.CriterioVonMisesLabel = uilabel(app.UIFigure);
            app.CriterioVonMisesLabel.Text = 'Criterio Von Mises:';
            app.CriterioTrescaLabel = uilabel(app.UIFigure);
            app.CriterioTrescaLabel.Text = 'Criterio Tresca:';
            app.FSVonMisesEditFieldLabel = uilabel(app.UIFigure);
            app.FSVonMisesEditFieldLabel.Text = 'FS Von Mises';
            app.FSVonMisesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FSTrescaEditFieldLabel = uilabel(app.UIFigure);
            app.FSTrescaEditFieldLabel.Text = 'FS Tresca';
            app.FSTrescaEditField = uieditfield(app.UIFigure, 'numeric');
            app.VERGRAFICASButton = uibutton(app.UIFigure, 'push');
            app.VERGRAFICASButton.ButtonPushedFcn = createCallbackFcn(app, @VERGRAFICASButtonPushed, true);
            app.VERGRAFICASButton.Text = 'VER GRAFICAS';
            app.SigmaPromedioEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaPromedioEditFieldLabel.Text = 'Sigma Promedio';
            app.SigmaPromedioEditField = uieditfield(app.UIFigure, 'numeric');
            app.EsfuerzodefluenciaEditFieldLabel = uilabel(app.UIFigure);
            app.EsfuerzodefluenciaEditFieldLabel.Text = 'Esfuerzo de fluencia';
            app.EsfuerzodefluenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaXEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXEditFieldLabel.Text = 'Sigma X';
            app.SigmaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaYEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaYEditFieldLabel.Text = 'Sigma Y';
            app.SigmaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValoresdeentradaLabel = uilabel(app.UIFigure);
            app.ValoresdeentradaLabel.Text = 'Valores de entrada';
            app.SigmaXYEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXYEditFieldLabel.Text = 'Sigma XY';
            app.SigmaXYEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnguloEditFieldLabel = uilabel(app.UIFigure);
            app.AnguloEditFieldLabel.Text = 'Angulo';
            app.AnguloEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Resultados_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end