classdef TransformacionDeEsfuerzos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        xyLabel                      matlab.ui.control.Label
        xLabel                       matlab.ui.control.Label
        yLabel                       matlab.ui.control.Label
        UnidadesF                    matlab.ui.control.DropDown
        UnidadesDropDown_4Label      matlab.ui.control.Label
        EsfuerzodefluenciaEditField  matlab.ui.control.NumericEditField
        EsfuerzodefluenciaEditFieldLabel  matlab.ui.control.Label
        InformacindelmaterialLabel   matlab.ui.control.Label
        nguloengradosLabel           matlab.ui.control.Label
        AlphaEditField               matlab.ui.control.NumericEditField
        AlphaEditFieldLabel          matlab.ui.control.Label
        UnidadesX                    matlab.ui.control.DropDown
        UnidadesXDropDownLabel       matlab.ui.control.Label
        UnidadesXY                   matlab.ui.control.DropDown
        UnidadesXYLabel              matlab.ui.control.Label
        UnidadesY                    matlab.ui.control.DropDown
        UnidadesYDropDownLabel       matlab.ui.control.Label
        SigmaXEditField              matlab.ui.control.NumericEditField
        SigmaXEditFieldLabel         matlab.ui.control.Label
        SigmaXYEditField             matlab.ui.control.NumericEditField
        SigmaXYEditFieldLabel        matlab.ui.control.Label
        SigmaYEditField              matlab.ui.control.NumericEditField
        SigmaYEditFieldLabel         matlab.ui.control.Label
        InstruccionesButton_2        matlab.ui.control.Button
        ResolverButton               matlab.ui.control.Button
        InsertelosvaloresdelosesfuerzosLabel  matlab.ui.control.Label
        AplicacinparatranformacindeesfuerzosplanosLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InstruccionesButton_2
        function InstruccionesButton_2Pushed(app, event)
            
            Instructivo_App

        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
          

            %Variables
                global sigmaX;
                global SigmaXu;
                global sigmaY;
                global SigmaYu;
                global sigmaXY;
                global SigmaXYu;
                global alpha;
                global eFluencia;
                global eFluenciaU;
                global Unidades;
                global Unidades2;
                global Unidades3;
                global Unidades4;
                global sigmaYPrima;
                global sigmaXPrima;
                global sigmaXYPrima;
                global sigmaXMax;
                global sigmaXMin;
                global sigmaXYMax;
                global alphaMaxP;
                global alphaMaxS;
                global fsTresca;
                global fsVonMises;
                global sigmaPromedio;
                
                sigmaX=app.SigmaXEditField.Value;
                sigmaY=app.SigmaYEditField.Value;
                sigmaXY=app.SigmaXYEditField.Value;
                alpha=app.AlphaEditField.Value;
                eFluencia=app.EsfuerzodefluenciaEditField.Value;

                    %Unidades Sigma Y
                     Unidades=str2double(app.UnidadesY.Value)

            if Unidades==2
                     SigmaYu=sigmaY;
            elseif Unidades==3
                     SigmaYu=sigmaY*1000;
           elseif Unidades==4
                     SigmaYu=sigmaY*1000000;
           elseif Unidades==5
                     SigmaYu=sigmaY*1000000000;
            end

         %Unidades Sigma X

                Unidades2=str2double(app.UnidadesX.Value)
             if Unidades2==2
                     SigmaXu=sigmaX;
            elseif Unidades2==3
                     SigmaXu=sigmaX*1000;
           elseif Unidades2==4
                     SigmaXu=sigmaX*1000000;
           elseif Unidades2==5
                     SigmaXu=sigmaX*1000000000;
             end

                       %Unidades Sigma XY
                   Unidades3=str2double(app.UnidadesXY.Value)
           if Unidades3==2
                     SigmaXYu=sigmaXY;
            elseif Unidades3==3
                     SigmaXYu=sigmaXY*1000;
           elseif Unidades3==4
                     SigmaXYu=sigmaXY*1000000;
           elseif Unidades3==5
                     SigmaXYu=sigmaXY*1000000000;
           end


                       %Unidades Sigma Fluencia
                   Unidades4=str2double(app.UnidadesF.Value)
           if Unidades4==2
                     eFluenciaU=eFluencia;
            elseif Unidades4==3
                     eFluenciaU=eFluencia*1000;
           elseif Unidades4==4
                     eFluenciaU=eFluencia*1000000;
           elseif Unidades4==5
                     eFluenciaU=eFluencia*1000000000;
             end
     
          
            %Calculo de esfuerzos transformados

             sigmaPromedio= (SigmaXu+SigmaYu)/2;
             sigmaXPrima= ((SigmaXu+SigmaYu)/2)+(((SigmaXu-SigmaYu)/2)*cosd(2*alpha))+SigmaXYu*sind(2*alpha);
             sigmaYPrima= ((SigmaXu+SigmaYu)/2)-((SigmaXu-SigmaYu)/2)*cosd(2*alpha)-SigmaXYu*sind(2*alpha);
             sigmaXYPrima=-((SigmaXu-SigmaYu)/2)*sind(2*alpha)+SigmaXYu*cosd(2*alpha);

             sigmaXMax= ((SigmaXu+SigmaYu)/2)+(((SigmaXu-SigmaYu)/2)^2+(SigmaXYu)^2)^(1/2);
             sigmaXMin= ((SigmaXu+SigmaYu)/2)-(((SigmaXu-SigmaYu)/2)^2+(SigmaXYu)^2)^(1/2);
             sigmaXYMax= (((SigmaXu-SigmaYu)/2)^2+(SigmaXYu^2))^(1/2);

             alphaMaxP= (atand((2*SigmaXYu)/(SigmaXu-SigmaYu)))/2;
             alphaMaxS= (atand(((SigmaXu-SigmaYu)/(2*SigmaXYu)))/2);

             %Criterios de falla

             fsTresca= (eFluenciaU/2)/sigmaXYMax
             fsVonMises= (eFluenciaU)/(((sigmaXMax^2)-sigmaXMax*sigmaXMin+(sigmaXMin^2))^(1/2))


             Resultados_App(alpha, eFluenciaU, sigmaPromedio, sigmaXPrima, sigmaYPrima, sigmaXYPrima, sigmaXMax, sigmaXMin, sigmaXYMax, alphaMaxP, alphaMaxS, fsTresca, fsVonMises, SigmaXu, SigmaYu, SigmaXYu);


        end

        % Value changed function: UnidadesY
        function UnidadesYValueChanged(app, event)
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Captura de pantalla 2023-05-03 193848.jpg');
            app.AplicacinparatranformacindeesfuerzosplanosLabel = uilabel(app.UIFigure);
            app.AplicacinparatranformacindeesfuerzosplanosLabel.Text = 'Aplicación para tranformación  de esfuerzos planos';
            app.InsertelosvaloresdelosesfuerzosLabel = uilabel(app.UIFigure);
            app.InsertelosvaloresdelosesfuerzosLabel.Text = {'Inserte los valores de los esfuerzos:'; 'Considere el signo si es a compresion (-)'; 'o a tensión (+)'};
            app.ResolverButton = uibutton(app.UIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.InstruccionesButton_2 = uibutton(app.UIFigure, 'push');
            app.InstruccionesButton_2.ButtonPushedFcn = createCallbackFcn(app, @InstruccionesButton_2Pushed, true);
            app.InstruccionesButton_2.Text = 'Instrucciones';
            app.SigmaYEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaYEditFieldLabel.Text = 'Sigma Y';
            app.SigmaYEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaXYEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXYEditFieldLabel.Text = 'Sigma XY';
            app.SigmaXYEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaXEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaXEditFieldLabel.Text = 'Sigma X';
            app.SigmaXEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnidadesYDropDownLabel = uilabel(app.UIFigure);
            app.UnidadesYDropDownLabel.Text = 'Unidades Y';
            app.UnidadesY = uidropdown(app.UIFigure);
            app.UnidadesY.Items = {'', 'Pa', 'kPa', 'MPa', 'GPa'};
            app.UnidadesY.ItemsData = {'1', '2', '3', '4', '5'};
            app.UnidadesY.ValueChangedFcn = createCallbackFcn(app, @UnidadesYValueChanged, true);
            app.UnidadesY.Value = '1';
            app.UnidadesXYLabel = uilabel(app.UIFigure);
            app.UnidadesXYLabel.Text = 'Unidades XY';
            app.UnidadesXY = uidropdown(app.UIFigure);
            app.UnidadesXY.Items = {'', 'Pa', 'kPa', 'MPa', 'GPa'};
            app.UnidadesXY.ItemsData = {'1', '2', '3', '4', '5'};
            app.UnidadesXY.Value = '1';
            app.UnidadesXDropDownLabel = uilabel(app.UIFigure);
            app.UnidadesXDropDownLabel.Text = 'Unidades X';
            app.UnidadesX = uidropdown(app.UIFigure);
            app.UnidadesX.Items = {'', 'Pa', 'kPa', 'MPa', 'GPa'};
            app.UnidadesX.ItemsData = {'1', '2', '3', '4', '5'};
            app.UnidadesX.Value = '1';
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.nguloengradosLabel = uilabel(app.UIFigure);
            app.nguloengradosLabel.Text = 'Ángulo en grados';
            app.InformacindelmaterialLabel = uilabel(app.UIFigure);
            app.InformacindelmaterialLabel.Text = 'Información del material';
            app.EsfuerzodefluenciaEditFieldLabel = uilabel(app.UIFigure);
            app.EsfuerzodefluenciaEditFieldLabel.Text = 'Esfuerzo de fluencia';
            app.EsfuerzodefluenciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.UnidadesDropDown_4Label = uilabel(app.UIFigure);
            app.UnidadesDropDown_4Label.Text = 'Unidades';
            app.UnidadesF = uidropdown(app.UIFigure);
            app.UnidadesF.Items = {'', 'Pa', 'kPa', 'MPa', 'GPa'};
            app.UnidadesF.ItemsData = {'1', '2', '3', '4', '5'};
            app.UnidadesF.Value = '1';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = '𝜎y';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = '𝜎x';
            app.xyLabel = uilabel(app.UIFigure);
            app.xyLabel.Text = '𝜎xy';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '𝛼';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransformacionDeEsfuerzos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end